classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Label                  matlab.ui.control.Label
        ECOLabel               matlab.ui.control.Label
        fancentricLabel        matlab.ui.control.Label
        FanDataTextArea        matlab.ui.control.TextArea
        FanDataTextAreaLabel   matlab.ui.control.Label
        DataDropDown           matlab.ui.control.DropDown
        DataDropDownLabel      matlab.ui.control.Label
        TestDropDown           matlab.ui.control.DropDown
        TestDropDownLabel      matlab.ui.control.Label
        HoldCheckBox           matlab.ui.control.CheckBox
        LocationDropDown       matlab.ui.control.DropDown
        LocationDropDownLabel  matlab.ui.control.Label
        FanDropDown            matlab.ui.control.DropDown
        FanDropDownLabel       matlab.ui.control.Label
        ThemeDropDown          matlab.ui.control.DropDown
        ThemeDropDownLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        F01_air_velocity;
        F02_air_velocity;
        F03_air_velocity;
        F04_air_velocity;
        F05_air_velocity;
        F06_air_velocity;
        F07_air_velocity;
        F08_air_velocity;
        F01_power_consumption;
        F02_power_consumption;
        F03_power_consumption;
        F04_power_consumption;
        F05_power_consumption;
        F06_power_consumption;
        F07_power_consumption;
        F08_power_consumption;
        F01_temperature;
        F02_temperature;
        F03_temperature;
        F04_temperature;
        F05_temperature;
        F06_temperature;
        F07_temperature;
        F08_temperature;
        time_s;
        dataType;
        testLower;          % lower bound of test indices
        testUpper;          % upper bound of test indices
        testUpperTolerance; % how high above the max value the upper bound should be
        Fields;
        Files;
        color1;             % primary theme color
        color2;             % secondary theme color
        fan_design_metadata;
        efficiency_metrics;
        CFM_metrics;
        axTitle;
    end
    
    methods (Access = private)
        
        function updateData(app)
            if strcmp(app.DataDropDown.Value, '_air_velocity') | strcmp(app.DataDropDown.Value, '_temperature')
                step = 35;
            elseif strcmp(app.DataDropDown.Value, '_power_consumption')
                step = 1;
            end

            locStr = app.LocationDropDown.Value;   % "L01".."L35"
            locNum = str2double(extractAfter(locStr, 'L')); % 1..35

            lower = app.testLower;
            upper = app.testUpper;

            for i = 1:numel(app.Files)
                S = load(app.Files{i});
                fn = fieldnames(S);
                T = S.(fn{1});

                % Candidate rows for this location within the test's upper bound
                idx = locNum:step:upper;

                % Enforce the test's lower bound and table bounds
                idx = idx(idx >= lower);
                H = height(T);
                idx = idx(idx >= 1 & idx <= H);

                % Validate variable name
                if isempty(idx) || ~any(ismember(string(app.dataType), string(T.Properties.VariableNames)))
                    app.(app.Fields{i}) = []; 
                    app.time_s = [];
                else
                % Extract rows for chosen variable and time (returns column/array)
                    dataCol = T{idx, app.dataType};      % safe now: idx in [1,H]
                    timeCol = T{idx, "time_sec"};        % assume "time_sec" exists

                    % Flatten to row vector
                    app.(app.Fields{i}) = reshape(dataCol, 1, []);
                    app.time_s = reshape(timeCol, 1, []);
                end
            end
        end

        function updatePlot(app)
            axPos = app.UIAxes.Position;   % capture

            fanStr  = app.FanDropDown.Value;        % "F01"
            locStr  = app.LocationDropDown.Value;  % "L01"
            testStr = app.TestDropDown.Value;      % "Low" etc.

            if app.HoldCheckBox.Value
                hold(app.UIAxes,'on');
            else
                cla(app.UIAxes);
                hold(app.UIAxes,'off');
                legend(app.UIAxes,'off');
            end

            fanNum     = str2double(extractAfter(fanStr,'F')); % 1..8
            dataField  = app.Fields{fanNum};
            y          = app.(dataField);

            % Guard against empty/invalid data
            if isempty(y) || ~any(isfinite(y(:)))
                cla(app.UIAxes);
                legend(app.UIAxes,'off');
                return;
            end

            % Plot (set DisplayName so legend works)
            label = sprintf('%s, %s, %s', fanStr, locStr, testStr);
            plot(app.UIAxes, app.time_s, y, 'DisplayName', label);

            % --- YLim based on max across ALL lines currently on axes ---
            lines = findall(app.UIAxes, 'Type', 'line');
            ymax  = -Inf;

            for k = 1:numel(lines)
                yd = lines(k).YData;
                if ~isempty(yd) && any(isfinite(yd(:)))
                    ymax = max(ymax, max(yd(:), [], 'omitnan'));
                end
            end

            if isfinite(ymax)
                ymax = round(ymax, 3, 'decimals');

                if strcmp(app.DataDropDown.Value, '_temperature')
                    if ymax > 26
                        app.UIAxes.YLim  = [24, (ymax + app.testUpperTolerance)];
                        app.UIAxes.YTick = 24:(round(((app.UIAxes.YLim(2) - 24)/4),3,"decimals")):app.UIAxes.YLim(2);
                    else
                        app.UIAxes.YLim  = [24, 26];
                        app.UIAxes.YTick = 24:0.5:26;
                    end
                else
                    app.UIAxes.YLim  = [0, (ymax + app.testUpperTolerance)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                end
            end

            if app.HoldCheckBox.Value
                lgd = legend(app.UIAxes,'show');
                lgd.Location = 'southoutside';
                app.UIAxes.Position = axPos;  % restore so axes size doesn't change
            else
                legend(app.UIAxes,'off');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % import files and graph the default graph when the app starts
            app.color1 = [0.13, 0.13, 0.13]; % very dark gray
            app.color2 = [0.96, 0.96, 0.96]; % very very very light gray
    
            app.fan_design_metadata = load('fan_design_metadata_table.mat');
            app.efficiency_metrics  = load('efficiency_metrics.mat');
            app.CFM_metrics         = load('CFM_metrics.mat');
            app.testUpperTolerance  = 0.1;

            app.Fields = {
                'F01_air_velocity', 'F02_air_velocity', ...
                'F03_air_velocity', 'F04_air_velocity', ...
                'F05_air_velocity', 'F06_air_velocity', ...
                'F07_air_velocity', 'F08_air_velocity'
                };

            app.Files = {
                'F01_air_velocity.mat', 'F02_air_velocity.mat', ...
                'F03_air_velocity.mat', 'F04_air_velocity.mat', ...
                'F05_air_velocity.mat', 'F06_air_velocity.mat', ...
                'F07_air_velocity.mat', 'F08_air_velocity.mat'
            };

            app.time_s = [];
            S = load(app.Files{1});

            % Assume the MAT file contains exactly one table variable:
            fn = fieldnames(S);
            T = S.(fn{1});  % <-- should be a table
            H = height(T);
            app.testUpper = floor(H/3);
            app.testLower = 1;
            step = 35;

            locStr = app.LocationDropDown.Value;   % "L01".."L35"
            locNum = str2double(extractAfter(locStr, 'L')); % 1..35

            lower = app.testLower;
            upper = app.testUpper;

            for i = 1:numel(app.Files)
                S = load(app.Files{i});
                fn = fieldnames(S);
                T = S.(fn{1});

                % Candidate rows for this location within the test's upper bound
                idx = locNum:step:upper;

                % Enforce the test's lower bound (this is where location affects the effective start)
                idx = idx(idx >= lower);

                app.(app.Fields{i})     = reshape(T{idx, "air_speed_mps"}, 1, []);
                app.time_s              = reshape(T{idx, "time_sec"},     1, []);
            end
            app.axTitle = title(app.UIAxes, 'Air Speed v.s. Time Elapsed', 'Color', app.color2);
            app.(app.Fields{1}) = reshape(T{app.testLower:35:app.testUpper, "air_speed_mps"}, 1, []);
            app.time_s = reshape(T{app.testLower:35:app.testUpper, "time_sec"}, 1, []);

            app.UIAxes.XLim = [0, 1200];
            app.UIAxes.XTick = [0, 300, 600, 900, 1200];
            app.UIAxes.YLim = [0, (round(max(app.F01_air_velocity),3,'decimals') + 0.1)];
            app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
            plot(app.UIAxes, app.time_s, app.F01_air_velocity);
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            app_theme = app.ThemeDropDown.Value;
            % actions to take based on different values
            switch app_theme
                case "Light"              % Light mode
                    app.color1 = [0.96, 0.96, 0.96]; % very very very light gray
                    app.color2 = [0, 0, 0];          % pitch black
                case "Dark"               % Dark mode
                    app.color1 = [0.13, 0.13, 0.13]; % very dark gray
                    app.color2 = [0.96, 0.96, 0.96]; % very very very light gray
                case "Midnight"           % 'Dark-er' mode
                    app.color1 = [0, 0, 0];          % pitch black
                    app.color2 = [0.5, 0.86, 0.86];  % light cyan (white is too bright, plus it looks cool)
            end
            % Defines attributes needing to = color1 when theme is changed
            color1_targets = {
                app.UIFigure,               'Color';
                app.ThemeDropDown,          'BackgroundColor';
                app.FanDropDown,            'BackgroundColor';
                app.LocationDropDown,       'BackgroundColor';
                app.TestDropDown,           'BackgroundColor';
                app.DataDropDown,           'BackgroundColor';
                app.UIAxes,                 'Color';
                app.FanDataTextArea,        'BackgroundColor'
            };

            % Defines attributes needing to = color2 when theme is changed
            color2_targets = {
                app.ThemeDropDown,          'FontColor';
                app.ThemeDropDownLabel,     'FontColor';
                app.LocationDropDown,       'FontColor';
                app.LocationDropDownLabel,  'FontColor';
                app.FanDropDown,            'FontColor';
                app.FanDropDownLabel,       'FontColor';
                app.TestDropDown,           'FontColor';
                app.TestDropDownLabel,      'FontColor';
                app.DataDropDown,           'FontColor';
                app.DataDropDownLabel,      'FontColor';
                app.UIAxes,                 'GridColor';
                app.UIAxes,                 'MinorGridColor';
                app.UIAxes,                 'XColor';
                app.UIAxes,                 'YColor';
                app.UIAxes,                 'AmbientLightColor';
                app.HoldCheckBox,           'FontColor';
                app.FanDataTextArea,        'FontColor';
                app.FanDataTextAreaLabel,   'FontColor'
            };

            % Each attribute in color1_targets is changed to color1
            for i = 1:size(color1_targets,1)
                set(color1_targets{i,1}, color1_targets{i,2}, app.color1);
            end

            % Each attribute in color2_targets is changed to color2
            for i = 1:size(color2_targets,1)
                set(color2_targets{i,1}, color2_targets{i,2}, app.color2);
            end
            app.updatePlot();
            app.UIAxes.Title = app.axTitle;
        end

        % Value changed function: FanDropDown
        function FanDropDownValueChanged(app, event)
            fanStr  = app.FanDropDown.Value;                 % e.g., "F01"
            fanNum  = str2double(extractAfter(fanStr,'F')); % -> 1..8

            S_fdm   = load('fan_design_metadata_table.mat');
            fn_fdm  = fieldnames(S_fdm);
            T_fdm   = S_fdm.(fn_fdm{1});

            S_em  = load('efficiency_metrics.mat');
            fn_em  = fieldnames(S_em);
            T_em   = S_em.(fn_em{1});

            S_cfm = load('CFM_metrics.mat');
            fn_cfm  = fieldnames(S_cfm);
            T_cfm   = S_cfm.(fn_cfm{1});

            % ---- safely unwrap cell contents ----
            bladeShape = T_fdm{fanNum, 2};
            if iscell(bladeShape), bladeShape = bladeShape{1}; end
            bladeShape = string(bladeShape);

            bladeLen   = T_fdm{fanNum, 4};
            if iscell(bladeLen), bladeLen = bladeLen{1}; end
            bladeLen = double(bladeLen);

            bladeAngle = T_fdm{fanNum, 6};
            if iscell(bladeAngle), bladeAngle = bladeAngle{1}; end
            bladeAngle = double(bladeAngle);

            cfm1 = T_cfm{fanNum, 2};
            if iscell(cfm1), cfm1 = cfm1{1}; end
            cfm1 = double(cfm1);

            cfm2 = T_cfm{fanNum, 3};
            if iscell(cfm2), cfm2 = cfm2{1}; end
            cfm2 = double(cfm2);

            em   = T_em{fanNum, 3};
            if iscell(em), em = em{1}; end
            em = double(em);

            eff = (cfm1 * cfm2 * 2118.8800031854 * 100) / em;

            app.FanDataTextArea.Value = sprintf( ...
                'Blade Shape: %s\nBlade Length: %.3g m\nBlade Angle: %.3g°\nFan Efficiency: %.4g%%', ...
                bladeShape, bladeLen, bladeAngle, eff);

            app.updatePlot();
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            Hold_on = app.HoldCheckBox.Value;
            switch Hold_on
                case 1
                    hold(app.UIAxes, 'on')
                case 0
                    hold(app.UIAxes, 'off')
            end            
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            app.updateData();
            app.updatePlot();
        end

        % Value changed function: TestDropDown
        function TestDropDownValueChanged(app, event)
            S = load(app.Files{1});
            fn = fieldnames(S);
            T = S.(fn{1});
            H = height(T);

            b1 = floor(H/3);
            b2 = floor(2*H/3);

            switch app.TestDropDown.Value
                case 'Low'
                    app.testLower = 1;
                    app.testUpper = b1;
                case 'Med'
                    app.testLower = b1 + 1;
                    app.testUpper = b2;
                case 'Hi'
                    app.testLower = b2 + 1;
                    app.testUpper = H;
            end

            app.updateData();
            app.updatePlot();
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            switch app.DataDropDown.Value   % this switch function is just to make sure that
                                            % the location drop down doesn't interfere with
                                            % the index of the power data,
                                            % and also to make sure the
                                            % reshape function picks the
                                            % right variable
                case '_air_velocity'
                    app.LocationDropDown.Visible = 'on';
                    app.LocationDropDownLabel.Visible = 'on';
                    app.dataType = "air_speed_mps";
                    app.testUpperTolerance = 0.1;
                    app.UIAxes.YLabel = ylabel(app.UIAxes, 'Air Speed (mps)');
                    app.UIAxes.Title = title(app.UIAxes, 'Air Speed v.s. Time Elapsed', 'Color', app.color2);
                case '_temperature'
                    app.LocationDropDown.Visible = 'on';
                    app.LocationDropDownLabel.Visible = 'on';
                    app.dataType = "temperature_C";
                    app.testUpperTolerance = 0.2;
                    app.UIAxes.YLabel = ylabel(app.UIAxes, 'Temperature (°C)');

                    app.UIAxes.Title = title(app.UIAxes, 'Temperature v.s. Time Elapsed', 'Color', app.color2);
                case '_power_consumption'
                    app.LocationDropDown.Visible = 'off';
                    app.LocationDropDownLabel.Visible = 'off';
                    app.LocationDropDown.Value = 'L01';
                    app.dataType = "power_W";
                    app.testUpperTolerance = 1;
                    app.UIAxes.YLabel = ylabel(app.UIAxes, 'Power (W)');
                    app.UIAxes.Title = title(app.UIAxes, 'Power Consumption v.s. Time Elapsed', 'Color', app.color2);
            end

            app.axTitle = app.UIAxes.Title;

            app.Fields = {
                ['F01' app.DataDropDown.Value], ['F02' app.DataDropDown.Value], ...
                ['F03' app.DataDropDown.Value], ['F04' app.DataDropDown.Value], ...
                ['F05' app.DataDropDown.Value], ['F06' app.DataDropDown.Value], ...
                ['F07' app.DataDropDown.Value], ['F08' app.DataDropDown.Value]
            };

            app.Files = {
                ['F01' app.DataDropDown.Value '.mat'], ['F02' app.DataDropDown.Value '.mat'], ...
                ['F03' app.DataDropDown.Value '.mat'], ['F04' app.DataDropDown.Value '.mat'], ...
                ['F05' app.DataDropDown.Value '.mat'], ['F06' app.DataDropDown.Value '.mat'], ...
                ['F07' app.DataDropDown.Value '.mat'], ['F08' app.DataDropDown.Value '.mat']
            };
            % Set to Low test bounds directly, then continue
            app.TestDropDown.Value = 'Low';
            S = load(app.Files{1});
            fn = fieldnames(S);
            T = S.(fn{1});
            H = height(T);
            b1 = floor(H/3);
            app.testLower = 1;
            app.testUpper = b1;

            app.HoldCheckBox.Value = 0;
            app.updateData();
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Air Speed v.s. Time Elapsed')
            xlabel(app.UIAxes, 'Time Elapsed (min)')
            ylabel(app.UIAxes, 'Air Speed (mps)')
            app.UIAxes.XLim = [0 1200];
            app.UIAxes.YLim = [0 0.5];
            app.UIAxes.XTick = [0 300 600 900 1200];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = [0 0.125 0.25 0.375 0.5];
            app.UIAxes.Toolbar = [];
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Light', 'Dark', 'Midnight'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Tooltip = {'Switch between different light-level based themes'};
            app.ThemeDropDown.Placeholder = 'Theme';
            app.ThemeDropDown.Value = 'Dark';
            app.FanDropDownLabel = uilabel(app.UIFigure);
            app.FanDropDownLabel.Text = 'Fan';
            app.FanDropDown = uidropdown(app.UIFigure);
            app.FanDropDown.Items = {'F01', 'F02', 'F03', 'F04', 'F05', 'F06', 'F07', 'F08'};
            app.FanDropDown.ValueChangedFcn = createCallbackFcn(app, @FanDropDownValueChanged, true);
            app.FanDropDown.Tooltip = {'Switch between fans. If hold is checked, selecting another fan will plot it on top of the current graph.'};
            app.FanDropDown.Placeholder = 'Theme';
            app.FanDropDown.Value = 'F01';
            app.LocationDropDownLabel = uilabel(app.UIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.UIFigure);
            app.LocationDropDown.Items = {'L01', 'L02', 'L03', 'L04', 'L05', 'L06', 'L07', 'L08', 'L09', 'L10', 'L11', 'L12', 'L13', 'L14', 'L15', 'L16', 'L17', 'L18', 'L19', 'L20', 'L21', 'L22', 'L23', 'L24', 'L25', 'L26', 'L27', 'L28', 'L29', 'L30', 'L31', 'L32', 'L33', 'L34', 'L35'};
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.LocationDropDown.Tooltip = {'Switch between sensor locations. If hold is checked, selecting another location will plot it on top of the current graph.'};
            app.LocationDropDown.Placeholder = 'Theme';
            app.LocationDropDown.Value = 'L01';
            app.HoldCheckBox = uicheckbox(app.UIFigure);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Tooltip = {'Allows plotting of multiple fans/variables'};
            app.HoldCheckBox.Text = 'Hold';
            app.TestDropDownLabel = uilabel(app.UIFigure);
            app.TestDropDownLabel.Text = 'Test';
            app.TestDropDown = uidropdown(app.UIFigure);
            app.TestDropDown.Items = {'Low', 'Med', 'Hi'};
            app.TestDropDown.ValueChangedFcn = createCallbackFcn(app, @TestDropDownValueChanged, true);
            app.TestDropDown.Tooltip = {'Switch between test runs. Test runs are sorted by speed setting. If hold is checked, selecting another test will plot it on top of the current graph.'};
            app.TestDropDown.Value = 'Low';
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {'Air Speed', 'Temperature', 'Power Consumption'};
            app.DataDropDown.ItemsData = {'_air_velocity', '_temperature', '_power_consumption'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Tooltip = {'Switch between data types. Changing this will turn off hold.'};
            app.DataDropDown.Placeholder = 'Air Speed';
            app.DataDropDown.Value = '_air_velocity';
            app.FanDataTextAreaLabel = uilabel(app.UIFigure);
            app.FanDataTextAreaLabel.Text = 'Fan Data';
            app.FanDataTextArea = uitextarea(app.UIFigure);
            app.FanDataTextArea.Value = {'Blade Shape: flat'; 'Blade Length: 0.55 m'; 'Blade Angle: 10°'; 'Fan Efficiency: 1399%'};
            app.fancentricLabel = uilabel(app.UIFigure);
            app.fancentricLabel.Text = ' fancentric           ';
            app.ECOLabel = uilabel(app.UIFigure);
            app.ECOLabel.Text = 'ECO';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end