classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HoldCheckBox           matlab.ui.control.CheckBox
        LocationDropDown       matlab.ui.control.DropDown
        LocationDropDownLabel  matlab.ui.control.Label
        FanDropDown            matlab.ui.control.DropDown
        FanDropDownLabel       matlab.ui.control.Label
        ThemeDropDown          matlab.ui.control.DropDown
        ThemeDropDownLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        F01_air_velocity; % exactly what it says it is
        F02_air_velocity; % exactly what it says it is
        F03_air_velocity; % exactly what it says it is
        F04_air_velocity; % exactly what it says it is
        F05_air_velocity; % exactly what it says it is
        F06_air_velocity; % exactly what it says it is
        F07_air_velocity; % exactly what it says it is
        F08_air_velocity; % exactly what it says it is
        time_s;
        test;
        velFields;
        velFiles;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % import files and graph the default graph when the app starts
            app.velFields = {
                'F01_air_velocity', 'F02_air_velocity', 'F03_air_velocity', 'F04_air_velocity', 'F05_air_velocity', 'F06_air_velocity', 'F07_air_velocity', 'F08_air_velocity'};

            app.velFiles = {
                'F01_air_velocity.mat','F02_air_velocity.mat','F03_air_velocity.mat','F04_air_velocity.mat','F05_air_velocity.mat','F06_air_velocity.mat','F07_air_velocity.mat','F08_air_velocity.mat'
            };

            app.time_s = []; % will be set inside the loop

            for i = 1:numel(app.velFiles)
                S = load(app.velFiles{i});

                % Assume the MAT file contains exactly one table variable:
                fn = fieldnames(S);
                T = S.(fn{1});  % <-- should be a table

                n = floor(height(T)/3);  % make sure it's an integer count
                
                app.(app.velFields{i}) = reshape(T{1:35:n, "air_speed_mps"}, 1, []);
                app.time_s = reshape(T{1:35:n, "time_sec"}, 1, []);
            end

            app.UIAxes.XLim = [0, 1200];
            app.UIAxes.XTick = [0, 300, 600, 900, 1200];
            app.UIAxes.YLim = [0, (round(max(app.F01_air_velocity),3,'decimals') + 0.1)];
            app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
            plot(app.UIAxes, app.time_s, app.F01_air_velocity);
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            app_theme = app.ThemeDropDown.Value;
            % actions to take based on different values
            switch app_theme
                case "Light"              % Light mode
                    color1 = [0.96, 0.96, 0.96]; % very very very light gray
                    color2 = [0, 0, 0];          % pitch black
                case "Dark"               % Dark mode
                    color1 = [0.13, 0.13, 0.13]; % very dark gray
                    color2 = [0.96, 0.96, 0.96]; % very very very light gray
                case "Midnight"           % 'Dark-er' mode
                    color1 = [0, 0, 0];          % pitch black
                    color2 = [0.2, 0.86, 0.86];  % light cyan (white is too bright, plus it looks cool)
            end
            % Defines attributes needing to = color1 when theme is changed
            color1_targets = {
                app.UIFigure,               'Color';
                app.ThemeDropDown,          'BackgroundColor';
                app.FanDropDown,            'BackgroundColor';
                app.LocationDropDown,       'BackgroundColor';
                app.UIAxes,                 'Color'
            };

            % Defines attributes needing to = color2 when theme is changed
            color2_targets = {
                app.ThemeDropDown,          'FontColor';
                app.ThemeDropDownLabel,     'FontColor';
                app.LocationDropDown,       'FontColor';
                app.LocationDropDownLabel,  'FontColor';
                app.FanDropDown,            'FontColor';
                app.FanDropDownLabel,       'FontColor';
                app.UIAxes,                 'GridColor';
                app.UIAxes,                 'MinorGridColor';
                app.UIAxes,                 'XColor';
                app.UIAxes,                 'YColor';
                app.UIAxes,                 'AmbientLightColor';
                app.HoldCheckBox,           'FontColor'
            };

            % Each attribute in color1_targets is changed to color1
            for i = 1:size(color1_targets,1)
                set(color1_targets{i,1}, color1_targets{i,2}, color1);
            end

            % Each attribute in color2_targets is changed to color2
            for i = 1:size(color2_targets,1)
                set(color2_targets{i,1}, color2_targets{i,2}, color2);
            end
        end

        % Value changed function: FanDropDown
        function FanDropDownValueChanged(app, event)
            fan_dropdown_fan_id = app.FanDropDown.Value;
            switch fan_dropdown_fan_id
                case 'F01'
                    app.UIAxes.YLim = [0, (round(max(app.F01_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F01_air_velocity)
                case 'F02'
                    app.UIAxes.YLim = [0, (round(max(app.F02_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F02_air_velocity)
                case 'F03'
                    app.UIAxes.YLim = [0, (round(max(app.F03_air_velocity),3,"decimals") + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F03_air_velocity)
                case 'F04'
                    app.UIAxes.YLim = [0, (round(max(app.F04_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F04_air_velocity)
                case 'F05'
                    app.UIAxes.YLim = [0, (round(max(app.F05_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F05_air_velocity)
                case 'F06'
                    app.UIAxes.YLim = [0, (round(max(app.F06_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F06_air_velocity)
                case 'F07'
                    app.UIAxes.YLim = [0, (round(max(app.F07_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F07_air_velocity)
                case 'F08'
                    app.UIAxes.YLim = [0, (round(max(app.F08_air_velocity),3,'decimals') + 0.1)];
                    app.UIAxes.YTick = 0:(round((app.UIAxes.YLim(2)/4),3,"decimals")):app.UIAxes.YLim(2);
                    plot(app.UIAxes, app.time_s, app.F08_air_velocity)
            end
            
        end

        % Value changed function: HoldCheckBox
        function HoldCheckBoxValueChanged(app, event)
            Hold_on = app.HoldCheckBox.Value;
            switch Hold_on
                case 1
                    hold(app.UIAxes, 'on')
                case 0
                    hold(app.UIAxes, 'off')
            end
            
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            location = app.LocationDropDown.Value;
            switch location
                case 'L01'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{1:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{1:35:n, "time_sec"}, 1, []);
                    end
                case 'L02'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{2:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{2:35:n, "time_sec"}, 1, []);
                    end
                case 'L03'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{3:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{3:35:n, "time_sec"}, 1, []);
                    end
                case 'L04'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{4:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{4:35:n, "time_sec"}, 1, []);
                    end
                case 'L05'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{5:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{5:35:n, "time_sec"}, 1, []);
                    end
                case 'L06'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{6:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{6:35:n, "time_sec"}, 1, []);
                    end
                case 'L07'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{7:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{7:35:n, "time_sec"}, 1, []);
                    end
                case 'L08'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{8:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{8:35:n, "time_sec"}, 1, []);
                    end
                case 'L09'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{9:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{9:35:n, "time_sec"}, 1, []);
                    end
                case 'L10'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{10:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{10:35:n, "time_sec"}, 1, []);
                    end
                case 'L11'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{11:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{11:35:n, "time_sec"}, 1, []);
                    end
                case 'L12'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{12:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{12:35:n, "time_sec"}, 1, []);
                    end
                case 'L13'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{13:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{13:35:n, "time_sec"}, 1, []);
                    end
                case 'L14'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{14:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{14:35:n, "time_sec"}, 1, []);
                    end
                case 'L15'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{15:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{15:35:n, "time_sec"}, 1, []);
                    end
                case 'L16'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{16:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{16:35:n, "time_sec"}, 1, []);
                    end
                case 'L17'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{17:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{17:35:n, "time_sec"}, 1, []);
                    end
                case 'L18'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{18:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{18:35:n, "time_sec"}, 1, []);
                    end
                case 'L19'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{19:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{19:35:n, "time_sec"}, 1, []);
                    end
                case 'L20'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{20:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{20:35:n, "time_sec"}, 1, []);
                    end
                case 'L21'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{21:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{21:35:n, "time_sec"}, 1, []);
                    end
                case 'L22'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{22:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{22:35:n, "time_sec"}, 1, []);
                    end
                case 'L23'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{23:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{23:35:n, "time_sec"}, 1, []);
                    end
                case 'L24'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{24:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{24:35:n, "time_sec"}, 1, []);
                    end
                case 'L25'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{25:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{25:35:n, "time_sec"}, 1, []);
                    end
                case 'L26'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{26:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{26:35:n, "time_sec"}, 1, []);
                    end
                case 'L27'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{27:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{27:35:n, "time_sec"}, 1, []);
                    end
                case 'L28'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{28:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{28:35:n, "time_sec"}, 1, []);
                    end
                case 'L29'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{29:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{29:35:n, "time_sec"}, 1, []);
                    end
                case 'L30'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{30:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{30:35:n, "time_sec"}, 1, []);
                    end
                case 'L31'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{31:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{31:35:n, "time_sec"}, 1, []);
                    end
                case 'L32'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{32:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{32:35:n, "time_sec"}, 1, []);
                    end
                case 'L33'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{33:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{33:35:n, "time_sec"}, 1, []);
                    end
                case 'L34'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{34:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{34:35:n, "time_sec"}, 1, []);
                    end
                case 'L35'
                    for i = 1:numel(app.velFiles)
                        S = load(app.velFiles{i});
                        fn = fieldnames(S);
                        T = S.(fn{1});
                        n = floor(height(T)/3);
                        app.(app.velFields{i}) = reshape(T{35:35:n, "air_speed_mps"}, 1, []);
                        app.time_s = reshape(T{35:35:n, "time_sec"}, 1, []);
                    end
            end
            FanDropDownValueChanged(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Air Speed v.s. Time Elapsed')
            xlabel(app.UIAxes, 'Time Elapsed (min)')
            ylabel(app.UIAxes, 'Air Speed (mps)')
            app.UIAxes.XLim = [0 1200];
            app.UIAxes.YLim = [0 0.5];
            app.UIAxes.XTick = [0 300 600 900 1200];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'};
            app.UIAxes.YTick = [0 0.125 0.25 0.375 0.5];
            app.UIAxes.Toolbar = [];
            app.ThemeDropDownLabel = uilabel(app.UIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.UIFigure);
            app.ThemeDropDown.Items = {'Light', 'Dark', 'Midnight'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Tooltip = {'Switch between different light-level based themes'};
            app.ThemeDropDown.Placeholder = 'Theme';
            app.ThemeDropDown.Value = 'Dark';
            app.FanDropDownLabel = uilabel(app.UIFigure);
            app.FanDropDownLabel.Text = 'Fan';
            app.FanDropDown = uidropdown(app.UIFigure);
            app.FanDropDown.Items = {'F01', 'F02', 'F03', 'F04', 'F05', 'F06', 'F07', 'F08'};
            app.FanDropDown.ValueChangedFcn = createCallbackFcn(app, @FanDropDownValueChanged, true);
            app.FanDropDown.Tooltip = {'Switch between fans. If hold is checked, selecting another fan will plot it on top of the current graph.'};
            app.FanDropDown.Placeholder = 'Theme';
            app.FanDropDown.Value = 'F01';
            app.LocationDropDownLabel = uilabel(app.UIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.UIFigure);
            app.LocationDropDown.Items = {'L01', 'L02', 'L03', 'L04', 'L05', 'L06', 'L07', 'L08', 'L09', 'L10', 'L11', 'L12', 'L13', 'L14', 'L15', 'L16', 'L17', 'L18', 'L19', 'L20', 'L21', 'L22', 'L23', 'L24', 'L25', 'L26', 'L27', 'L28', 'L29', 'L30', 'L31', 'L32', 'L33', 'L34', 'L35'};
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.LocationDropDown.Tooltip = {'Switch between sensor locations. If hold is checked, selecting another location will plot it on top of the current graph.'};
            app.LocationDropDown.Placeholder = 'Theme';
            app.LocationDropDown.Value = 'L01';
            app.HoldCheckBox = uicheckbox(app.UIFigure);
            app.HoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @HoldCheckBoxValueChanged, true);
            app.HoldCheckBox.Tooltip = {'Allows plotting of multiple fans/variables'};
            app.HoldCheckBox.Text = 'Hold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end